classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CellposeXMLBrowseButton    matlab.ui.control.Button
        CellposeXMLEditFieldLabel  matlab.ui.control.Label
        CellposeXMLEditField       matlab.ui.control.EditField
        W1BrowseButton             matlab.ui.control.Button
        W1Label                    matlab.ui.control.Label
        W1EditField                matlab.ui.control.EditField
        W2BrowseButton             matlab.ui.control.Button
        W2Label                    matlab.ui.control.Label
        W2EditField                matlab.ui.control.EditField
        MasksBrowseButton          matlab.ui.control.Button
        MasksEditFieldLabel        matlab.ui.control.Label
        MasksEditField             matlab.ui.control.EditField
        OutputBrowseButton         matlab.ui.control.Button
        OutputEditFieldLabel       matlab.ui.control.Label
        OutputEditField            matlab.ui.control.EditField
        JobNameEditFieldLabel      matlab.ui.control.Label
        JobNameEditField           matlab.ui.control.EditField
        RunButton                  matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        PlotCheckBox               matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CellposeXMLBrowseButton
        function CellposeXMLBrowseButtonPushed(app, event)
            [retfile, retpath] = uigetfile('*.xml');
            if retfile ~= 0
                app.CellposeXMLEditField.Value = [retpath, retfile];
                app.W1EditField.Value = [retpath, '0_W1'];
                app.W2EditField.Value = [retpath, '0_W2'];
                app.MasksEditField.Value = [retpath, '0_W1_masks'];
                app.OutputEditField.Value = retpath;
            end
        end

        % Button pushed function: W1BrowseButton
        function W1BrowseButtonPushed(app, event)
            retpath = uigetdir();
            if retpath ~= 0
                app.W1EditField.Value = retpath;
            end
        end

        % Button pushed function: W2BrowseButton
        function W2BrowseButtonPushed(app, event)
            retpath = uigetdir();
            if retpath ~= 0
                app.W2EditField.Value = retpath;
            end
        end

        % Button pushed function: MasksBrowseButton
        function MasksBrowseButtonPushed(app, event)
            retpath = uigetdir();
            if retpath ~= 0
                app.MasksEditField.Value = retpath;
            end
        end

        % Button pushed function: OutputBrowseButton
        function OutputBrowseButtonPushed(app, event)
            retpath = uigetdir();
            if retpath ~= 0
                app.OutputEditField.Value = retpath;
            end
        end

        % Value changed function: W1EditField
        function W1EditFieldValueChanged(app, event)
            value = app.W1EditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.TextArea.Value{end+1} = 'Check files exist...';
            w1_folder = app.W1EditField.Value;
            w2_folder = app.W2EditField.Value;
            masks_folder = app.MasksEditField.Value;
           
            % remaining arguments
            xml_filename = app.CellposeXMLEditField.Value;
            output_folder = app.OutputEditField.Value;
            jobname = app.JobNameEditField.Value;
            
            main(app, jobname, xml_filename, w1_folder, w2_folder, masks_folder, output_folder);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CellposeXMLBrowseButton = uibutton(app.UIFigure, 'push');
            app.CellposeXMLBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @CellposeXMLBrowseButtonPushed, true);
            app.CellposeXMLBrowseButton.Tag = 'XMLBrowse';
            app.CellposeXMLBrowseButton.Text = 'Browse';
            app.CellposeXMLEditFieldLabel = uilabel(app.UIFigure);
            app.CellposeXMLEditFieldLabel.Text = 'Cellpose XML';
            app.CellposeXMLEditField = uieditfield(app.UIFigure, 'text');
            app.W1BrowseButton = uibutton(app.UIFigure, 'push');
            app.W1BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @W1BrowseButtonPushed, true);
            app.W1BrowseButton.Text = 'Browse';
            app.W1Label = uilabel(app.UIFigure);
            app.W1Label.Text = 'W1';
            app.W1EditField = uieditfield(app.UIFigure, 'text');
            app.W1EditField.ValueChangedFcn = createCallbackFcn(app, @W1EditFieldValueChanged, true);
            app.W2BrowseButton = uibutton(app.UIFigure, 'push');
            app.W2BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @W2BrowseButtonPushed, true);
            app.W2BrowseButton.Text = 'Browse';
            app.W2Label = uilabel(app.UIFigure);
            app.W2Label.Text = 'W2';
            app.W2EditField = uieditfield(app.UIFigure, 'text');
            app.MasksBrowseButton = uibutton(app.UIFigure, 'push');
            app.MasksBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MasksBrowseButtonPushed, true);
            app.MasksBrowseButton.Text = 'Browse';
            app.MasksEditFieldLabel = uilabel(app.UIFigure);
            app.MasksEditFieldLabel.Text = 'Masks';
            app.MasksEditField = uieditfield(app.UIFigure, 'text');
            app.OutputBrowseButton = uibutton(app.UIFigure, 'push');
            app.OutputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OutputBrowseButtonPushed, true);
            app.OutputBrowseButton.Text = 'Browse';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.JobNameEditFieldLabel = uilabel(app.UIFigure);
            app.JobNameEditFieldLabel.Text = 'Job Name';
            app.JobNameEditField = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.PlotCheckBox = uicheckbox(app.UIFigure);
            app.PlotCheckBox.Text = 'Plot intensities (might be slow)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensities Ratio')
            xlabel(app.UIAxes, 'Frame')
            ylabel(app.UIAxes, 'Ratio')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end